classdef ViconExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExperimentSpecificEventEditField  matlab.ui.control.EditField
        ExperimentSpecificEventEditFieldLabel  matlab.ui.control.Label
        ViconLamp                       matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        ConnecttoViconButton            matlab.ui.control.Button
        CreatorEmmaReznickUpdated022023Label  matlab.ui.control.Label
        ViconDataExtractionforFRBLabel  matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SelectDataTypesLabel            matlab.ui.control.Label
        SelectSaveTargetButton          matlab.ui.control.Button
        SelectTrialsButton              matlab.ui.control.Button
        MakeStructureButton             matlab.ui.control.Button
        StructureNameEditField          matlab.ui.control.EditField
        StructureNameLabel              matlab.ui.control.Label
        DataTypesPanel                  matlab.ui.container.Panel
        BonesCheckBox                   matlab.ui.control.CheckBox
        JointCentersCheckBox            matlab.ui.control.CheckBox
        EMGCheckBox                     matlab.ui.control.CheckBox
        SubjectDetailsCheckBox          matlab.ui.control.CheckBox
        EventsCheckBox                  matlab.ui.control.CheckBox
        JointPowersCheckBox             matlab.ui.control.CheckBox
        JointForceCheckBox              matlab.ui.control.CheckBox
        JointMomentCheckBox             matlab.ui.control.CheckBox
        JointVelocitiesCheckBox         matlab.ui.control.CheckBox
        JointAnglesCheckBox             matlab.ui.control.CheckBox
        MarkersCheckBox                 matlab.ui.control.CheckBox
        ForcePlateCheckBox              matlab.ui.control.CheckBox
    end


    properties (Access = private)
        trial %trial names
        data_path % data location
        target_path %save path
        vicon
        viconbool = false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % if ~isdeployed 
            %     addpath("..\Subfunctions\")
            % end

            if isempty(which('processTrials'))
                error('Manually add the subfunctions folder to path for running the GUI in the editor.')
            end

        end

        % Button pushed function: SelectTrialsButton
        function SelectTrialsButtonPushed(app, event)
            [app.trial,app.data_path] = uigetfile('*.x1d',...
                'Select One or More Files', ...
                'MultiSelect', 'on');
            figure(app.UIFigure); % Prevents matlab bug from changing focus https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
        end

        % Button pushed function: MakeStructureButton
        function MakeStructure(app, event)
            
            %toss in some warnings
            if isempty(app.trial)
                warning('No Trials Selected')
            end
            if isempty(app.StructureNameEditField.Value)
                warning('No Structure Name Chosen')
            end
            if isempty(app.target_path)
                warning('No Target Path Selected')
            end
            if ~app.viconbool
                warning('Not Connected to Vicon')
            end
            if isempty(app.trial)||  isempty(app.StructureNameEditField.Value) || isempty(app.target_path) || ~app.viconbool
                app.StatusLamp.Color = 'r';
                return
            end
            app.StatusLamp.Color = 'y';
            
            % initialize bools
            bool_marker = app.MarkersCheckBox.Value;
            bool_FP = app.ForcePlateCheckBox.Value;
            bool_Jangle = app.JointAnglesCheckBox.Value;
            bool_Jvel = app.JointVelocitiesCheckBox.Value;
            bool_Jmom = app.JointMomentCheckBox.Value;
            bool_Jpow = app.JointPowersCheckBox.Value;
            bool_Jforce = app.JointForceCheckBox.Value;
            bool_event = app.EventsCheckBox.Value;
            bool_subDet = app.SubjectDetailsCheckBox.Value;
            bool_centers = app.JointCentersCheckBox.Value;
            bool_bones = app.BonesCheckBox.Value;
            bool_EMG = app.EMGCheckBox.Value;
           

            try
                processTrials(app.vicon, app.StructureNameEditField.Value, app.trial, app.data_path, app.target_path, bool_marker, bool_FP, bool_Jangle,...
                    bool_Jvel, bool_Jmom, bool_Jpow, bool_Jforce, bool_event, bool_subDet, app.ExperimentSpecificEventEditField.Value, bool_EMG, bool_bones, bool_centers);
                app.StatusLamp.Color = 'g';
            catch ME
                app.StatusLamp.Color = 'r';
                rethrow(ME)
            end

        end

        % Callback function
        function StructureNameEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SelectSaveTargetButton
        function SelectSaveTargetButtonPushed(app, event)
            app.target_path = uigetdir;
            figure(app.UIFigure); % Prevents matlab bug from changing focus https://www.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
        end

        % Button pushed function: ConnecttoViconButton
        function ConnecttoViconButtonPushed(app, event)
            %% Connect to Vicon Nexus
            try
            warning('off','MATLAB:mpath:nameNonexistentOrNotADirectory')
            try
                addpath('C:\Program Files\Vicon\Nexus2.15\SDK\MATLAB')
                addpath('C:\Program Files\Vicon\Nexus2.15\SDK\Win64')
                addpath('C:\Program Files\Vicon\Nexus2.16\SDK\Matlab')
                addpath('C:\Program Files\Vicon\Nexus2.16\SDK\Win64')
            catch
                warning('on','MATLAB:mpath:nameNonexistentOrNotADirectory')
                error('Ensure that the Vicon SDK is installed and located in the expected path.')
            end
            warning('on','MATLAB:mpath:nameNonexistentOrNotADirectory')
            app.vicon = ViconNexus;
            app.viconbool = true;
            app.ViconLamp.Color = 'g';
            catch
                app.ViconLamp.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DataTypesPanel = uipanel(app.UIFigure);
            app.DataTypesPanel.Title = 'Data Types';
            app.ForcePlateCheckBox = uicheckbox(app.DataTypesPanel);
            app.ForcePlateCheckBox.Text = {'Force Plate'; ''};
            app.MarkersCheckBox = uicheckbox(app.DataTypesPanel);
            app.MarkersCheckBox.Text = 'Markers';
            app.JointAnglesCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointAnglesCheckBox.Text = 'Joint Angles';
            app.JointVelocitiesCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointVelocitiesCheckBox.Text = 'Joint Velocities';
            app.JointMomentCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointMomentCheckBox.Text = 'Joint Moment';
            app.JointForceCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointForceCheckBox.Text = 'Joint Force';
            app.JointPowersCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointPowersCheckBox.Text = 'Joint Powers';
            app.EventsCheckBox = uicheckbox(app.DataTypesPanel);
            app.EventsCheckBox.Text = 'Events';
            app.SubjectDetailsCheckBox = uicheckbox(app.DataTypesPanel);
            app.SubjectDetailsCheckBox.Text = 'Subject Details';
            app.EMGCheckBox = uicheckbox(app.DataTypesPanel);
            app.EMGCheckBox.Text = 'EMG';
            app.JointCentersCheckBox = uicheckbox(app.DataTypesPanel);
            app.JointCentersCheckBox.Text = 'Joint Centers';
            app.BonesCheckBox = uicheckbox(app.DataTypesPanel);
            app.BonesCheckBox.Text = 'Bones';
            app.StructureNameLabel = uilabel(app.UIFigure);
            app.StructureNameLabel.Text = '1) Structure Name';
            app.StructureNameEditField = uieditfield(app.UIFigure, 'text');
            app.MakeStructureButton = uibutton(app.UIFigure, 'push');
            app.MakeStructureButton.ButtonPushedFcn = createCallbackFcn(app, @MakeStructure, true);
            app.MakeStructureButton.Text = '6) Make Structure';
            app.SelectTrialsButton = uibutton(app.UIFigure, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrialsButtonPushed, true);
            app.SelectTrialsButton.Text = '2) Select Trials';
            app.SelectSaveTargetButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveTargetButtonPushed, true);
            app.SelectSaveTargetButton.Text = '3) Select Save Target';
            app.SelectDataTypesLabel = uilabel(app.UIFigure);
            app.SelectDataTypesLabel.Text = {'4) Select Data Types'; ''};
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ViconDataExtractionforFRBLabel = uilabel(app.UIFigure);
            app.ViconDataExtractionforFRBLabel.Text = 'Vicon Data Extraction for FRB';
            app.CreatorEmmaReznickUpdated022023Label = uilabel(app.UIFigure);
            app.CreatorEmmaReznickUpdated022023Label.Text = {'Creator: Emma Reznick'; 'Updated: 02/2023'};
            app.ConnecttoViconButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoViconButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoViconButtonPushed, true);
            app.ConnecttoViconButton.Text = '5) Connect to Vicon';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ViconLamp = uilamp(app.UIFigure);
            app.ExperimentSpecificEventEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentSpecificEventEditFieldLabel.Text = 'Experiment Specific Event';
            app.ExperimentSpecificEventEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViconExtraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end