classdef ViconExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ViconLamp                       matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        ConnecttoViconButton            matlab.ui.control.Button
        CreatorEmmaReznick2022Label     matlab.ui.control.Label
        ViconDataExtractionforFRBLabel  matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SelectDataLabel                 matlab.ui.control.Label
        SelectSaveTargetButton          matlab.ui.control.Button
        SelectTrialsButton              matlab.ui.control.Button
        MakeStructureButton             matlab.ui.control.Button
        StructureNameEditField          matlab.ui.control.EditField
        StructureNameLabel              matlab.ui.control.Label
        DataTypesLabel                  matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        RawDataNode                     matlab.ui.container.TreeNode
        MarkersNode                     matlab.ui.container.TreeNode
        ForceplatesNode                 matlab.ui.container.TreeNode
        KinematicsNode                  matlab.ui.container.TreeNode
        JointAnglesNode                 matlab.ui.container.TreeNode
        JointVelocitiesNode             matlab.ui.container.TreeNode
        KineticsNode                    matlab.ui.container.TreeNode
        JointForceNode                  matlab.ui.container.TreeNode
        JointMomentNode                 matlab.ui.container.TreeNode
        JointPowerNode                  matlab.ui.container.TreeNode
        MiscNode                        matlab.ui.container.TreeNode
        EventsNode                      matlab.ui.container.TreeNode
        SubjectDetailsNode              matlab.ui.container.TreeNode
    end


    properties (Access = private)
        trial %trial names
        data_path % data location
        target_path %save path
        vicon
        viconbool = false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectTrialsButton
        function SelectTrialsButtonPushed(app, event)
            [app.trial,app.data_path] = uigetfile('*.x1d',...
                'Select One or More Files', ...
                'MultiSelect', 'on');
        end

        % Button pushed function: MakeStructureButton
        function MakeStructure(app, event)
            clc
            %toss in some warnings
            if isempty(app.trial)
                warning('No Trials Selected')
            end
            if isempty(app.StructureNameEditField.Value)
                warning('No Structure Name Chosen')
            end
            if isempty(app.target_path)
                warning('No Target Path Selected')
            end
            if ~app.viconbool
                warning('Not Connected to Vicon')
            end
            if isempty(app.trial)||  isempty(app.StructureNameEditField.Value) || isempty(app.target_path) || ~app.viconbool
                app.StatusLamp.Color = 'r';
                return
            end
            app.StatusLamp.Color = 'y';
            % initialize bools
            bool_marker = false;
            bool_FP = false;
            bool_Jangle = false;
            bool_Jvel = false;
            bool_Jmom = false;
            bool_Jpow = false;
            bool_Jforce = false;
            bool_event = false;
            bool_subDet = false;
            %             bool_strideTime = false;

            %collect checked boxes and mark bool appropriately
            checkedBoxes = app.Tree.CheckedNodes;
            for data_select = 1:length(checkedBoxes)
                if contains(checkedBoxes(data_select).Text, 'Markers')
                    bool_marker = true;
                elseif contains(checkedBoxes(data_select).Text, 'Forceplates')
                    bool_FP = true;
                elseif contains(checkedBoxes(data_select).Text, 'Joint Angles')
                    bool_Jangle = true;
                elseif contains(checkedBoxes(data_select).Text, 'Joint Velocities')
                    bool_Jvel = true;
                elseif contains(checkedBoxes(data_select).Text, 'Joint Moment')
                    bool_Jmom = true;
                elseif contains(checkedBoxes(data_select).Text, 'Joint Power')
                    bool_Jpow = true;
                elseif contains(checkedBoxes(data_select).Text, 'Joint Force')
                    bool_Jforce = true;
                elseif contains(checkedBoxes(data_select).Text, 'Events')
                    bool_event = true;
                elseif contains(checkedBoxes(data_select).Text, 'Subject Details')
                    bool_subDet = true;
                    %                 elseif contains(checkedBoxes(data_select).Text, 'Stride Time')
                    %                     bool_strideTime = true;
                end
            end

            try
                ViconExtractionLEADER(app.vicon, app.StructureNameEditField.Value, app.trial, app.data_path, app.target_path, bool_marker, bool_FP, bool_Jangle,...
                    bool_Jvel, bool_Jmom, bool_Jpow, bool_Jforce, bool_event, bool_subDet);
                app.StatusLamp.Color = 'g';
            catch
                app.StatusLamp.Color = 'r';
            end

        end

        % Callback function
        function StructureNameEditFieldValueChanged(app, event)

        end

        % Button pushed function: SelectSaveTargetButton
        function SelectSaveTargetButtonPushed(app, event)
            app.target_path = uigetdir;
        end

        % Button pushed function: ConnecttoViconButton
        function ConnecttoViconButtonPushed(app, event)
            %% Connect to Vicon Nexus
            try
            addpath('C:\Program Files (x86)\Vicon\Nexus2.12\SDK\Matlab')
            addpath('C:\Program Files (x86)\Vicon\Nexus2.12\SDK\Win64')
            app.vicon = ViconNexus;
            app.viconbool = true;
            app.ViconLamp.Color = 'g';
            catch
                app.ViconLamp.Color = 'r';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.RawDataNode = uitreenode(app.Tree);
            app.RawDataNode.Text = 'Raw Data';
            app.MarkersNode = uitreenode(app.RawDataNode);
            app.MarkersNode.Text = 'Markers';
            app.ForceplatesNode = uitreenode(app.RawDataNode);
            app.ForceplatesNode.Text = 'Forceplates';
            app.KinematicsNode = uitreenode(app.Tree);
            app.KinematicsNode.Text = 'Kinematics';
            app.JointAnglesNode = uitreenode(app.KinematicsNode);
            app.JointAnglesNode.Text = 'Joint Angles';
            app.JointVelocitiesNode = uitreenode(app.KinematicsNode);
            app.JointVelocitiesNode.Text = 'Joint Velocities';
            app.KineticsNode = uitreenode(app.Tree);
            app.KineticsNode.Text = 'Kinetics';
            app.JointForceNode = uitreenode(app.KineticsNode);
            app.JointForceNode.Text = 'Joint Force';
            app.JointMomentNode = uitreenode(app.KineticsNode);
            app.JointMomentNode.Text = 'Joint Moment';
            app.JointPowerNode = uitreenode(app.KineticsNode);
            app.JointPowerNode.Text = 'Joint Power';
            app.MiscNode = uitreenode(app.Tree);
            app.MiscNode.Text = 'Misc.';
            app.EventsNode = uitreenode(app.MiscNode);
            app.EventsNode.Text = 'Events';
            app.SubjectDetailsNode = uitreenode(app.MiscNode);
            app.SubjectDetailsNode.Text = 'Subject Details';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.MarkersNode, app.ForceplatesNode, app.JointAnglesNode, app.JointVelocitiesNode, app.JointForceNode, app.JointMomentNode, app.JointPowerNode, app.EventsNode, app.SubjectDetailsNode, app.RawDataNode, app.KinematicsNode, app.KineticsNode, app.MiscNode];
            app.DataTypesLabel = uilabel(app.UIFigure);
            app.DataTypesLabel.Text = 'Data Types';
            app.StructureNameLabel = uilabel(app.UIFigure);
            app.StructureNameLabel.Text = '1) Structure Name';
            app.StructureNameEditField = uieditfield(app.UIFigure, 'text');
            app.MakeStructureButton = uibutton(app.UIFigure, 'push');
            app.MakeStructureButton.ButtonPushedFcn = createCallbackFcn(app, @MakeStructure, true);
            app.MakeStructureButton.Text = '6) Make Structure';
            app.SelectTrialsButton = uibutton(app.UIFigure, 'push');
            app.SelectTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTrialsButtonPushed, true);
            app.SelectTrialsButton.Text = '2) Select Trials';
            app.SelectSaveTargetButton = uibutton(app.UIFigure, 'push');
            app.SelectSaveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSaveTargetButtonPushed, true);
            app.SelectSaveTargetButton.Text = '3) Select Save Target';
            app.SelectDataLabel = uilabel(app.UIFigure);
            app.SelectDataLabel.Text = {'4) Select Data -->'; ''};
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ViconDataExtractionforFRBLabel = uilabel(app.UIFigure);
            app.ViconDataExtractionforFRBLabel.Text = 'Vicon Data Extraction for FRB';
            app.CreatorEmmaReznick2022Label = uilabel(app.UIFigure);
            app.CreatorEmmaReznick2022Label.Text = 'Creator: Emma Reznick 2022';
            app.ConnecttoViconButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoViconButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoViconButtonPushed, true);
            app.ConnecttoViconButton.Text = '5) Connect to Vicon';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ViconLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViconExtraction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end